classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        StartAnalysisButton    matlab.ui.control.Button
        SelectFileButton       matlab.ui.control.Button
        FileSelectedNoneLabel  matlab.ui.control.Label
        AudioLengthLabel       matlab.ui.control.Label
        BeatsPerMinuteLabel    matlab.ui.control.Label
    end

    
    properties (Access = public)
         filename % Stores file name
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartAnalysisButton
        function start(app, event)
            [origfile,origfs] = audioread(app.filename);
            A = origfile(1:10:end);
            fs = origfs/10;
            figure;
            plot(A);
            title("Plot of Original Sound Wave");
            xlabel("Samples");
            time = length(A)/fs;
            app.AudioLengthLabel.Text="Audio Length: "+time+" seconds";
            app.AudioLengthLabel.Enable=true;
            app.AudioLengthLabel.Visible=true;
            bpm = 0;
            figure;
            bp = bandpass(A,[70 120],fs);
            plot(bp);
            title("Plot of Bandpass Filtered Sound Wave");
            xlabel("Samples");
            m = prctile(bp,99)
            figure;
            findpeaks(bp,'MinPeakHeight',m,'MinPeakDistance',250);
            title("Plot of Filtered Sound Wave with Peaks");
            xlabel("Samples");
            peaks = findpeaks(bp,'MinPeakHeight',m,'MinPeakDistance',250);
            beats = round(length(peaks)/2);
            bpm = (beats/time)*60;
            app.BeatsPerMinuteLabel.Text = "Beats Per Minute: "+bpm;
            app.BeatsPerMinuteLabel.Visible = true;
            app.BeatsPerMinuteLabel.Enable = true;
            
        end

        % Button pushed function: SelectFileButton
        function selfile(app, event)

            app.filename = uigetfile('*.*');
            app.StartAnalysisButton.Enable = true;
            app.FileSelectedNoneLabel.Text = "File Selected: "+app.filename;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartAnalysisButton = uibutton(app.UIFigure, 'push');
            app.StartAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @start, true);
            app.StartAnalysisButton.Enable = 'off';
            app.StartAnalysisButton.Tooltip = {''};
            app.StartAnalysisButton.Text = 'Start Analysis';
            app.SelectFileButton = uibutton(app.UIFigure, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @selfile, true);
            app.SelectFileButton.Text = 'Select File';
            app.FileSelectedNoneLabel = uilabel(app.UIFigure);
            app.FileSelectedNoneLabel.Text = {'File Selected: None'; ''};
            app.AudioLengthLabel = uilabel(app.UIFigure);
            app.AudioLengthLabel.Enable = 'off';
            app.AudioLengthLabel.Visible = 'off';
            app.AudioLengthLabel.Text = 'Audio Length';
            app.BeatsPerMinuteLabel = uilabel(app.UIFigure);
            app.BeatsPerMinuteLabel.Enable = 'off';
            app.BeatsPerMinuteLabel.Visible = 'off';
            app.BeatsPerMinuteLabel.Text = 'Beats Per Minute: ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end